classdef ViewerMainGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        SignalViewerLabel     matlab.ui.control.Label
        UnitStepButton        matlab.ui.control.Button
        RampButton            matlab.ui.control.Button
        ImpulseButton         matlab.ui.control.Button
        ButtonGroup           matlab.ui.container.ButtonGroup
        DiscreteTimeButton    matlab.ui.control.RadioButton
        ContinuousTimeButton  matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UnitStepButton
        function UnitStepButtonPushed(app, event)
            [t,u] = continuousunitstep();
            plot(app.UIAxes, t, u);
            xlim(app.UIAxes, [-6 6]);
            ylim(app.UIAxes, [0 6]);
        end

        % Button pushed function: RampButton
        function RampButtonPushed(app, event)
            [t,r] = continuousramp();
            plot(app.UIAxes, t, r);
            xlim(app.UIAxes, [-6 6]);
            ylim(app.UIAxes, [0 6]);
        end

        % Button pushed function: ImpulseButton
        function ImpulseButtonPushed(app, event)
            [t,d] = continuousimpulse();
            plot(app.UIAxes, t, d);
            xlim(app.UIAxes, [-6 6]);
            ylim(app.UIAxes, [0 6]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, {''; ''})
            app.UIAxes.XTick = [-6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6];
            app.UIAxes.XTickLabel = {'-inf'; '-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'; 'inf'};
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.YTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.SignalViewerLabel = uilabel(app.UIFigure);
            app.SignalViewerLabel.Text = {'Signal Viewer'; ''};
            app.UnitStepButton = uibutton(app.UIFigure, 'push');
            app.UnitStepButton.ButtonPushedFcn = createCallbackFcn(app, @UnitStepButtonPushed, true);
            app.UnitStepButton.Text = 'Unit Step';
            app.RampButton = uibutton(app.UIFigure, 'push');
            app.RampButton.ButtonPushedFcn = createCallbackFcn(app, @RampButtonPushed, true);
            app.RampButton.Text = {'Ramp'; ''};
            app.ImpulseButton = uibutton(app.UIFigure, 'push');
            app.ImpulseButton.ButtonPushedFcn = createCallbackFcn(app, @ImpulseButtonPushed, true);
            app.ImpulseButton.Text = 'Impulse';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.DiscreteTimeButton = uiradiobutton(app.ButtonGroup);
            app.DiscreteTimeButton.Text = 'Discrete Time';
            app.ContinuousTimeButton = uiradiobutton(app.ButtonGroup);
            app.ContinuousTimeButton.Text = 'Continuous Time';
            app.ContinuousTimeButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewerMainGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end